classdef proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_3                     matlab.ui.control.Label
        SubcategoriaDropDown        matlab.ui.control.DropDown
        SubcategoriaDropDownLabel   matlab.ui.control.Label
        PromocionDropDown           matlab.ui.control.DropDown
        PromocionDropDownLabel      matlab.ui.control.Label
        NumeroAutosEditField        matlab.ui.control.NumericEditField
        NumeroAutosEditFieldLabel   matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        KgLabel                     matlab.ui.control.Label
        PrediccionCategoradeunproductoLabel  matlab.ui.control.Label
        MarcaDropDown               matlab.ui.control.DropDown
        MarcaDropDownLabel          matlab.ui.control.Label
        ColorDropDown               matlab.ui.control.DropDown
        ColorDropDownLabel          matlab.ui.control.Label
        MesDropDown                 matlab.ui.control.DropDown
        MesDropDownLabel            matlab.ui.control.Label
        ResultadocategoradeproductoEditField  matlab.ui.control.EditField
        ResultadocategoradeproductoEditFieldLabel  matlab.ui.control.Label
        PredecirButton              matlab.ui.control.Button
        CanalDropDown               matlab.ui.control.DropDown
        CanalDropDownLabel          matlab.ui.control.Label
        AoDropDown                  matlab.ui.control.DropDown
        AoDropDownLabel             matlab.ui.control.Label
        ClaseDropDown               matlab.ui.control.DropDown
        ClaseLabel                  matlab.ui.control.Label
        VentaLabel                  matlab.ui.control.Label
        ClienteLabel                matlab.ui.control.Label
        ProductoLabel               matlab.ui.control.Label
        TiendaDropDown              matlab.ui.control.DropDown
        TiendaDropDownLabel         matlab.ui.control.Label
        EducacionDropDown           matlab.ui.control.DropDown
        EducacionDropDownLabel      matlab.ui.control.Label
        TieneCasaButtonGroup        matlab.ui.container.ButtonGroup
        NoButton                    matlab.ui.control.RadioButton
        SiButton                    matlab.ui.control.RadioButton
        EstadocivilButtonGroup      matlab.ui.container.ButtonGroup
        CasadoButton                matlab.ui.control.RadioButton
        SolteroButton               matlab.ui.control.RadioButton
        GeneroButtonGroup           matlab.ui.container.ButtonGroup
        FemeninoButton              matlab.ui.control.RadioButton
        MasculinoButton             matlab.ui.control.RadioButton
        EdadEditField               matlab.ui.control.NumericEditField
        EdadEditFieldLabel          matlab.ui.control.Label
        IngresoanualEditField       matlab.ui.control.NumericEditField
        IngresoanualEditFieldLabel  matlab.ui.control.Label
        NumHijosEditField           matlab.ui.control.NumericEditField
        NumHijosEditFieldLabel      matlab.ui.control.Label
        PrecioEditField             matlab.ui.control.NumericEditField
        PrecioEditFieldLabel        matlab.ui.control.Label
        CostoEditField              matlab.ui.control.NumericEditField
        CostoEditFieldLabel         matlab.ui.control.Label
        PesoEditField               matlab.ui.control.NumericEditField
        PesoEditFieldLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredecirButton
        function PredecirButtonPushed(app, event)

             clase = app.ClaseDropDown.Value; % Obtener la clase seleccionada del Dropdown
                switch clase
                    case 'Deluxe'
                        clasev = [1, 0, 0];
                    case 'Economica'
                        clasev = [0, 1, 0];
                    case 'Normal'
                        clasev = [0, 0, 1];
                    otherwise
                        clasev = [0, 0, 0]; % Valor predeterminado o manejo de error
                end

             color = app.ColorDropDown.Value; % Obtener el color seleccionado del Dropdown
                switch color
                    case 'Amarillo'
                        colorv = [1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Azul'
                        colorv = [0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Blanco'
                        colorv = [0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Gris'
                        colorv = [0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Marron'
                        colorv = [0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Naranja'
                        colorv = [0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Negro'
                        colorv = [0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0];
                    case 'Oro'
                        colorv = [0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0];
                    case 'Plata'
                        colorv = [0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0];
                    case 'Rojo'
                        colorv = [0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0];
                    case 'Rosa'
                        colorv = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0];
                    case 'Transparente'
                        colorv = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0];
                    case 'Verde'
                        colorv = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0];
                    case 'Celeste'
                        colorv = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1];
                    otherwise
                        colorv = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0]; % Valor predeterminado o manejo de error
                end

             subcategoria = app.SubcategoriaDropDown.Value; % Obtener la subcategoría seleccionada del Dropdown
                switch subcategoria
                    case 'Accesorios de equipos'
                        subcategoriav = [1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Aires acondicionados'
                        subcategoriav = [0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Calentadores de agua'
                        subcategoriav = [0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Equipos portatiles'
                        subcategoriav = [0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Impresoras y escaneres fax'
                        subcategoriav = [0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Juegos reunidos'
                        subcategoriav = [0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Lavadoras y secadoras'
                        subcategoriav = [0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0];
                    case 'Lamparas'
                        subcategoriav = [0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0];
                    case 'Microondas'
                        subcategoriav = [0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0];
                    case 'Monitores'
                        subcategoriav = [0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0];
                    case 'Maquinas de cafe'
                        subcategoriav = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0];
                    case 'Proyectores y pantallas'
                        subcategoriav = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0];
                    case 'Refrigeradores'
                        subcategoriav = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0];
                    case 'Ventiladores'
                        subcategoriav = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1];
                    otherwise
                        subcategoriav = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0]; % Valor predeterminado o manejo de error
                end

                marca = app.MarcaDropDown.Value; % Obtener la marca seleccionada del Dropdown
                switch marca
                    case 'Samsung'
                        marcav = [1, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'LG'
                        marcav = [0, 1, 0, 0, 0, 0, 0, 0, 0];
                    case 'Fabrikam'
                        marcav = [0, 0, 1, 0, 0, 0, 0, 0, 0];
                    case 'Litware'
                        marcav = [0, 0, 0, 1, 0, 0, 0, 0, 0];
                    case 'Xiaomi'
                        marcav = [0, 0, 0, 0, 1, 0, 0, 0, 0];
                    case 'Proseware'
                        marcav = [0, 0, 0, 0, 0, 1, 0, 0, 0];
                    case 'Southridge Video'
                        marcav = [0, 0, 0, 0, 0, 0, 1, 0, 0];
                    case 'Tailspin Toys'
                        marcav = [0, 0, 0, 0, 0, 0, 0, 1, 0];
                    case 'WWImporters'
                        marcav = [0, 0, 0, 0, 0, 0, 0, 0, 1]; % Otra marca
                    otherwise
                        marcav = [0, 0, 0, 0, 0, 0, 0, 0, 0]; % Valor predeterminado o manejo de error
                end

                educacion = app.EducacionDropDown.Value; % Obtener el tipo de educación seleccionado del Dropdown
                switch educacion
                    case 'Bachillerato'
                        educacionv = [1, 0, 0, 0, 0];
                    case 'Bachillerato parcial'
                        educacionv = [0, 1, 0, 0, 0];
                    case 'Grado'
                        educacionv = [0, 0, 1, 0, 0];
                    case 'Grado parcial'
                        educacionv = [0, 0, 0, 1, 0];
                    case 'Master'
                        educacionv = [0, 0, 0, 0, 1];
                    otherwise
                        educacionv = [0, 0, 0, 0, 0]; % Valor predeterminado o manejo de error
                end

                tienda = app.TiendaDropDown.Value; % Obtener la tienda seleccionada del Dropdown
                switch tienda
                    case 'Tienda en linea Asia'
                        tiendav = [1, 0, 0];
                    case 'Tienda en linea Europa'
                        tiendav = [0, 1, 0];
                    case 'Tienda en linea Norteamerica'
                        tiendav = [0, 0, 1];
                    otherwise
                        tiendav = [0, 0, 0]; % Valor predeterminado o manejo de error
                end

                canal = app.CanalDropDown.Value; % Obtener el canal seleccionado del Dropdown
                switch canal
                    case 'Almacen'
                        canalv = [1, 0, 0, 0];
                    case 'Catalogo'
                        canalv = [0, 1, 0, 0];
                    case 'Distribuidor'
                        canalv = [0, 0, 1, 0];
                    case 'Inventario'
                        canalv = [0, 0, 0, 1];
                    otherwise
                        canalv = [0, 0, 0, 0]; % Valor predeterminado o manejo de error
                end

                mes = app.MesDropDown.Value; % Obtener el mes seleccionado del Dropdown
                switch mes
                    case 'Abril'
                        mesv = [1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Agosto'
                        mesv = [0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Diciembre'
                        mesv = [0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Enero'
                        mesv = [0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Febrero'
                        mesv = [0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0];
                    case 'Julio'
                        mesv = [0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0];
                    case 'Junio'
                        mesv = [0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0];
                    case 'Marzo'
                        mesv = [0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0];
                    case 'Mayo'
                        mesv = [0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0];
                    case 'Noviembre'
                        mesv = [0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0];
                    case 'Octubre'
                        mesv = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0];
                    case 'Septiembre'
                        mesv = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1];
                    otherwise
                        mesv = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0]; % Valor predeterminado o manejo de error
                end


            anio = app.AoDropDown.Value; % Obtener el valor seleccionado del Dropdown
            switch anio
                    case '2007'
                        aniov = [1, 0, 0];
                    case '2008'
                        aniov = [0, 1, 0];
                    case '2009'
                        aniov = [0, 0, 1];
                    otherwise
                        aniov = [0, 0, 0]; % Valor predeterminado o manejo de error
            end

            promocion = app.PromocionDropDown.Value;
            switch tienda
            case 'Tienda en linea Asia'
                switch promocion
                    case 'Promocion de primavera'
                        promocionv = [0, 0, 0, 1, 0, 0, 0, 0, 0];
                    case 'Promocion de vacaciones'
                        promocionv = [0, 0, 0, 0, 1, 0, 0, 0, 0];
                    case 'Promocion de verano'
                        promocionv = [0, 0, 0, 0, 0, 1, 0, 0, 0];
                    otherwise
                        promocionv = [0, 0, 0, 0, 0, 0, 0, 0, 0]; % Valor predeterminado o manejo de error
                end
            case 'Tienda en linea Europa'
                switch promocion
                    case 'Promocion de primavera'
                        promocionv = [0, 0, 0, 0, 0, 0, 1, 0, 0];
                    case 'Promocion de vacaciones'
                        promocionv = [0, 0, 0, 0, 0, 0, 0, 1, 0];
                    case 'Promocion de verano'
                        promocionv = [0, 0, 0, 0, 0, 0, 0, 0, 1];
                    otherwise
                        promocionv = [0, 0, 0, 0, 0, 0, 0, 0, 0]; % Valor predeterminado o manejo de error
                end
            case 'Tienda en linea Norteamerica'
                switch promocion
                    case 'Promocion de primavera'
                        promocionv = [1, 0, 0, 0, 0, 0, 0, 0, 0];
                    case 'Promocion de vacaciones'
                        promocionv = [0, 1, 0, 0, 0, 0, 0, 0, 0];
                    case 'Promocion de verano'
                        promocionv = [0, 0, 1, 0, 0, 0, 0, 0, 0];
                    otherwise
                        promocionv = [0, 0, 0, 0, 0, 0, 0, 0, 0]; % Valor predeterminado o manejo de error
                end
            otherwise
                promocionv = [0, 0, 0, 0, 0, 0, 0, 0, 0]; % Valor predeterminado o manejo de error
           end

            if app.MasculinoButton.Value
                generov = [0, 1]; % Género Masculino
            elseif app.FemeninoButton.Value
                generov = [1, 0]; % Género Femenino
            else
                generov = [0, 0]; % Valor predeterminado o manejo de error
            end

            if app.SolteroButton.Value
                estCV = [0, 1]; 
            elseif app.CasadoButton.Value
                estCV = [1, 0]; 
            else
                estCV = [0, 0]; % Valor predeterminado o manejo de error
            end

            if app.NoButton.Value
                casav = [1, 0];
            elseif app.SiButton.Value
                casav = [0, 1]; 
            else
                casav = [0, 0]; % Valor predeterminado o manejo de error
            end
            valor = 0;
            minCosto = 150;
            maxCosto = 88000;
            CostoValue = app.CostoEditField.Value; 
            CostoN = (CostoValue - minCosto) / (maxCosto - minCosto);

            minPeso = 1;
            maxPeso = 1785;
            PesoValue = app.PesoEditField.Value; 
            PesoN = (PesoValue - minPeso) / (maxPeso - minPeso);

            minPrecio = 294;
            maxPrecio = 265290;
            PrecioValue = app.PrecioEditField.Value; 
            PrecioN = (PrecioValue - minPrecio) / (maxPrecio - minPrecio);

            minIngre = 10000;
            maxIngre = 170000;
            IngreValue = app.IngresoanualEditField.Value; 
            IngresoN = (IngreValue - minIngre) / (maxIngre - minIngre);

            minEdad = 29;
            maxEdad = 100;
            EdadValue = app.EdadEditField.Value;
            EdadN = (EdadValue - minEdad) / (maxEdad - minEdad);

            minHijos = 0;
            maxHijos = 5;
            HijosValue = app.NumHijosEditField.Value;
            HijosN = (HijosValue - minHijos) / (maxHijos - minHijos);

            minAutos = 0;
            maxAutos = 4;
            AutosValue = app.NumeroAutosEditField.Value;
            AutosN = (AutosValue - minAutos) / (maxAutos - minAutos);

            
            rowToPredict = [PesoN CostoN PrecioN IngresoN EdadN HijosN AutosN aniov mesv marcav valor clasev colorv subcategoriav canalv promocionv generov estCV casav educacionv tiendav];
            fprintf("Vector de características: ");
             fprintf("[%g] ", rowToPredict);
             fprintf("Numero columnas: ");
             fprintf("[%g] ",size(rowToPredict,2));
             fprintf("\n");
             str = load('lastmodel.mat')
             [yfit,scores] = str.lastmodel.predictFcn(rowToPredict);
             
             if(yfit == 1)
                   app.ResultadocategoradeproductoEditField.Value = "Electrodomesticos";
             end
              if(yfit == 2)
                 app.ResultadocategoradeproductoEditField.Value = "Juegos y Jugeutes";
              end
               if(yfit == 3)
                 app.ResultadocategoradeproductoEditField.Value = "Equipos";
            end

        end

        % Value changed function: PesoEditField
        function PesoEditFieldValueChanged(app, event)
            value = app.PesoEditField.Value;
            if value < 1 || value > 1785
            % Mostrar un mensaje de error o realizar alguna acción para indicar al usuario que el valor es inválido
            errordlg('Peso en kilogramos debe estar entre el rango de 1kg a 1785kg', 'Error');
            % Restaurar el valor anterior
            app.PesoEditField.Value = 1;
            end
        end

        % Value changed function: CostoEditField
        function CostoEditFieldValueChanged(app, event)
            value = app.CostoEditField.Value;
            if value < 150 || value > 88000
            % Mostrar un mensaje de error o realizar alguna acción para indicar al usuario que el valor es inválido
            errordlg('Costo debe estar entre el rango de 150$ a 88000$', 'Error');
            
            % Restaurar el valor anterior
            app.CostoEditField.Value = 150;
            end
        end

        % Value changed function: PrecioEditField
        function PrecioEditFieldValueChanged(app, event)
            value = app.PrecioEditField.Value;
            if value < 294 || value > 265290
            % Mostrar un mensaje de error o realizar alguna acción para indicar al usuario que el valor es inválido
            errordlg('Precio debe estar entre el rango de 294$ a 265290$', 'Error');
            
            % Restaurar el valor anterior
            app.PrecioEditField.Value = 294;
            end
        end

        % Value changed function: IngresoanualEditField
        function IngresoanualEditFieldValueChanged(app, event)
            value = app.IngresoanualEditField.Value;
            if value < 10000 || value > 170000
            % Mostrar un mensaje de error o realizar alguna acción para indicar al usuario que el valor es inválido
            errordlg('Ingreso anual debe estar entre el rango de 10000$ a 170000$', 'Error');
            
            % Restaurar el valor anterior
            app.IngresoanualEditField.Value = 10000;
            end
        end

        % Value changed function: EdadEditField
        function EdadEditFieldValueChanged(app, event)
            value = app.EdadEditField.Value;
            if value < 29 || value > 99
            % Mostrar un mensaje de error o realizar alguna acción para indicar al usuario que el valor es inválido
            errordlg('Edad debe estar entre el rango de 29 a 99', 'Error');
            
            % Restaurar el valor anterior
            app.EdadEditField.Value = 29;
            end
        end

        % Value changed function: NumHijosEditField
        function NumHijosEditFieldValueChanged(app, event)
            value = app.NumHijosEditField.Value;
             if value < 0 || value > 5
            % Mostrar un mensaje de error o realizar alguna acción para indicar al usuario que el valor es inválido
            errordlg('Numero de hijos debe estar entre el rango de 0 a 5', 'Error');
            
            % Restaurar el valor anterior
            app.NumHijosEditField.Value = 0;
            end
        end

        % Value changed function: NumeroAutosEditField
        function NumeroAutosEditFieldValueChanged(app, event)
            value = app.NumeroAutosEditField.Value;
            if value < 0 || value > 4
            % Mostrar un mensaje de error o realizar alguna acción para indicar al usuario que el valor es inválido
            errordlg('Numero de autos debe estar entre el rango de 0 a 4', 'Error');
            
            % Restaurar el valor anterior
            app.NumeroAutosEditField.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PesoEditFieldLabel = uilabel(app.UIFigure);
            app.PesoEditFieldLabel.Text = 'Peso:';
            app.PesoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PesoEditField.ValueDisplayFormat = '%.0f';
            app.PesoEditField.ValueChangedFcn = createCallbackFcn(app, @PesoEditFieldValueChanged, true);
            app.PesoEditField.Tag = 'txtPeso';
            app.PesoEditField.Value = 1;
            app.CostoEditFieldLabel = uilabel(app.UIFigure);
            app.CostoEditFieldLabel.Text = 'Costo:';
            app.CostoEditField = uieditfield(app.UIFigure, 'numeric');
            app.CostoEditField.ValueDisplayFormat = '%.0f';
            app.CostoEditField.ValueChangedFcn = createCallbackFcn(app, @CostoEditFieldValueChanged, true);
            app.CostoEditField.Tag = 'txtCosto';
            app.CostoEditField.Value = 150;
            app.PrecioEditFieldLabel = uilabel(app.UIFigure);
            app.PrecioEditFieldLabel.Text = 'Precio:';
            app.PrecioEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrecioEditField.ValueDisplayFormat = '%.0f';
            app.PrecioEditField.ValueChangedFcn = createCallbackFcn(app, @PrecioEditFieldValueChanged, true);
            app.PrecioEditField.Tag = 'txtPrecio';
            app.PrecioEditField.Value = 294;
            app.NumHijosEditFieldLabel = uilabel(app.UIFigure);
            app.NumHijosEditFieldLabel.Text = 'Num Hijos:';
            app.NumHijosEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumHijosEditField.ValueChangedFcn = createCallbackFcn(app, @NumHijosEditFieldValueChanged, true);
            app.NumHijosEditField.Tag = 'txtHijos';
            app.IngresoanualEditFieldLabel = uilabel(app.UIFigure);
            app.IngresoanualEditFieldLabel.Text = 'Ingreso anual:';
            app.IngresoanualEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngresoanualEditField.ValueDisplayFormat = '%.0f';
            app.IngresoanualEditField.ValueChangedFcn = createCallbackFcn(app, @IngresoanualEditFieldValueChanged, true);
            app.IngresoanualEditField.Tag = 'txtIngreso';
            app.IngresoanualEditField.Value = 10000;
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad:';
            app.EdadEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdadEditField.ValueChangedFcn = createCallbackFcn(app, @EdadEditFieldValueChanged, true);
            app.EdadEditField.Tag = 'txtEdad';
            app.EdadEditField.Value = 29;
            app.GeneroButtonGroup = uibuttongroup(app.UIFigure);
            app.GeneroButtonGroup.Title = 'Genero';
            app.GeneroButtonGroup.Tag = 'rbGenero';
            app.MasculinoButton = uiradiobutton(app.GeneroButtonGroup);
            app.MasculinoButton.Text = 'Masculino';
            app.MasculinoButton.Value = true;
            app.FemeninoButton = uiradiobutton(app.GeneroButtonGroup);
            app.FemeninoButton.Text = 'Femenino';
            app.EstadocivilButtonGroup = uibuttongroup(app.UIFigure);
            app.EstadocivilButtonGroup.Title = 'Estado civil';
            app.EstadocivilButtonGroup.Tag = 'rbEstadoC';
            app.SolteroButton = uiradiobutton(app.EstadocivilButtonGroup);
            app.SolteroButton.Text = 'Soltero';
            app.SolteroButton.Value = true;
            app.CasadoButton = uiradiobutton(app.EstadocivilButtonGroup);
            app.CasadoButton.Text = 'Casado';
            app.TieneCasaButtonGroup = uibuttongroup(app.UIFigure);
            app.TieneCasaButtonGroup.Title = 'Tiene Casa';
            app.TieneCasaButtonGroup.Tag = 'rbCasa';
            app.SiButton = uiradiobutton(app.TieneCasaButtonGroup);
            app.SiButton.Text = 'Si';
            app.SiButton.Value = true;
            app.NoButton = uiradiobutton(app.TieneCasaButtonGroup);
            app.NoButton.Text = 'No';
            app.EducacionDropDownLabel = uilabel(app.UIFigure);
            app.EducacionDropDownLabel.Text = 'Educacion';
            app.EducacionDropDown = uidropdown(app.UIFigure);
            app.EducacionDropDown.Items = {'Bachillerato', 'Bachillerato parcial', 'Grado', 'Grado parcial', 'Master'};
            app.EducacionDropDown.Tag = 'gdEducacion';
            app.EducacionDropDown.Value = 'Bachillerato';
            app.TiendaDropDownLabel = uilabel(app.UIFigure);
            app.TiendaDropDownLabel.Text = 'Tienda:';
            app.TiendaDropDown = uidropdown(app.UIFigure);
            app.TiendaDropDown.Items = {'Tienda en linea Asia', 'Tienda en linea Norteamerica', 'Tienda en linea Europa'};
            app.TiendaDropDown.Tag = 'gdTienda';
            app.TiendaDropDown.Value = 'Tienda en linea Asia';
            app.ProductoLabel = uilabel(app.UIFigure);
            app.ProductoLabel.Text = 'Producto';
            app.ClienteLabel = uilabel(app.UIFigure);
            app.ClienteLabel.Text = 'Cliente';
            app.VentaLabel = uilabel(app.UIFigure);
            app.VentaLabel.Text = 'Venta';
            app.ClaseLabel = uilabel(app.UIFigure);
            app.ClaseLabel.Text = 'Clase:';
            app.ClaseDropDown = uidropdown(app.UIFigure);
            app.ClaseDropDown.Items = {'Deluxe', 'Normal', 'Economica'};
            app.ClaseDropDown.Tag = 'gdClase';
            app.ClaseDropDown.Value = 'Deluxe';
            app.AoDropDownLabel = uilabel(app.UIFigure);
            app.AoDropDownLabel.Text = 'Año';
            app.AoDropDown = uidropdown(app.UIFigure);
            app.AoDropDown.Items = {'2007', '2008', '2009'};
            app.AoDropDown.Tag = 'gdAño';
            app.AoDropDown.Value = '2007';
            app.CanalDropDownLabel = uilabel(app.UIFigure);
            app.CanalDropDownLabel.Text = 'Canal';
            app.CanalDropDown = uidropdown(app.UIFigure);
            app.CanalDropDown.Items = {'Almacen', 'Catalogo', 'Distribuidor', 'Inventario'};
            app.CanalDropDown.Tag = 'gdCanal';
            app.CanalDropDown.Value = 'Almacen';
            app.PredecirButton = uibutton(app.UIFigure, 'push');
            app.PredecirButton.ButtonPushedFcn = createCallbackFcn(app, @PredecirButtonPushed, true);
            app.PredecirButton.Text = 'Predecir';
            app.ResultadocategoradeproductoEditFieldLabel = uilabel(app.UIFigure);
            app.ResultadocategoradeproductoEditFieldLabel.Text = 'Resultado categoría de producto:';
            app.ResultadocategoradeproductoEditField = uieditfield(app.UIFigure, 'text');
            app.ResultadocategoradeproductoEditField.Tag = 'txtResultado';
            app.MesDropDownLabel = uilabel(app.UIFigure);
            app.MesDropDownLabel.Text = 'Mes:';
            app.MesDropDown = uidropdown(app.UIFigure);
            app.MesDropDown.Items = {'Enero', 'Febrero', 'Marzo', 'Abril', 'Mayo', 'Junio', 'Julio', 'Agosto', 'Septiembre', 'Octubre', 'Noviembre', 'Diciembre'};
            app.MesDropDown.Tag = 'gdMes';
            app.MesDropDown.Value = 'Enero';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Amarillo', 'Azul', 'Blanco', 'Gris', 'Naranja', 'Marron', 'Negro', 'Oro', 'Plata', 'Rojo', 'Rosa', 'Transparente', 'Celeste', 'Verde'};
            app.ColorDropDown.Tag = 'gdColor';
            app.ColorDropDown.Value = 'Amarillo';
            app.MarcaDropDownLabel = uilabel(app.UIFigure);
            app.MarcaDropDownLabel.Text = 'Marca:';
            app.MarcaDropDown = uidropdown(app.UIFigure);
            app.MarcaDropDown.Items = {'Samsung', 'LG', 'Fabrikam', 'Litware', 'Southridge Video', 'Proseware', 'Xiaomi', 'Tailspin Toys', 'Sony', 'WWImporters'};
            app.MarcaDropDown.Tag = 'gdMarca';
            app.MarcaDropDown.Value = 'Samsung';
            app.PrediccionCategoradeunproductoLabel = uilabel(app.UIFigure);
            app.PrediccionCategoradeunproductoLabel.Text = 'Prediccion Categoría de un producto';
            app.KgLabel = uilabel(app.UIFigure);
            app.KgLabel.Text = 'Kg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '$';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '$';
            app.NumeroAutosEditFieldLabel = uilabel(app.UIFigure);
            app.NumeroAutosEditFieldLabel.Text = 'Numero Autos:';
            app.NumeroAutosEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumeroAutosEditField.ValueChangedFcn = createCallbackFcn(app, @NumeroAutosEditFieldValueChanged, true);
            app.PromocionDropDownLabel = uilabel(app.UIFigure);
            app.PromocionDropDownLabel.Text = 'Promocion:';
            app.PromocionDropDown = uidropdown(app.UIFigure);
            app.PromocionDropDown.Items = {'Promocion de primavera', 'Promocion de vacaciones', 'Promocion de verano'};
            app.PromocionDropDown.Value = 'Promocion de verano';
            app.SubcategoriaDropDownLabel = uilabel(app.UIFigure);
            app.SubcategoriaDropDownLabel.Text = 'Subcategoria:';
            app.SubcategoriaDropDown = uidropdown(app.UIFigure);
            app.SubcategoriaDropDown.Items = {'Accesorios de equipos', 'Aires acondicionados', 'Calentadores de agua', 'Equipos portatiles', 'Impresoras y escaneres fax', 'Juegos reunidos', 'Lavadoras y secadoras', 'Lamparas', 'Microondas', 'Monitores', 'Maquinas de cafe', 'Proyectores y pantallas', 'Refrigeradores'};
            app.SubcategoriaDropDown.Value = 'Accesorios de equipos';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '$';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end